classdef patternDisplayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        exportgraphicsButton   matlab.ui.control.Button
        inputColorSeries       matlab.ui.control.EditField
        inputcolorseriesLabel  matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        closedPatternButton    matlab.ui.control.RadioButton
        linearPatternButton    matlab.ui.control.RadioButton
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGroup, inputColorSeries
        function ButtonGroupSelectionChanged(app, event)
     inputText = app.inputColorSeries.Value;
           colorSeriesVector = convertInputToVector(inputText);
    if isempty(colorSeriesVector)
        return;
    end

    selectedButton = app.ButtonGroup.SelectedObject;
    switch selectedButton.Text
        case 'linearPattern'
            plotLinearPattern(app.UIAxes,colorSeriesVector);
                title(app.UIAxes,'Linear Pattern ');
        case 'closedPattern'
            plotClosedPattern(app.UIAxes,colorSeriesVector);
                title(app.UIAxes,'Closed pattern ');
        otherwise
            disp('Unknown pattern selected');
    
 
    end
       
        end

        % Button pushed function: exportgraphicsButton
        function exportgraphicsButtonPushed(app, event)
            % Get the UIAxes object
    ax = app.UIAxes;
    
    % Open a save file dialog
    [filename, pathname] = uiputfile({'*.png','PNG Image (*.png)';...
                                      '*.jpg','JPEG Image (*.jpg)';...
                                      '*.pdf','PDF Document (*.pdf)'},...
                                      'Save Image As');
    
    if isequal(filename,0) || isequal(pathname,0)
        disp('User selected Cancel')
    else
        fullpath = fullfile(pathname, filename);
        [~, ~, ext] = fileparts(fullpath);
        
        % Save the image
        switch lower(ext)
            case '.png'
                exportgraphics(ax, fullpath, 'Resolution', 300);
            case '.jpg'
                exportgraphics(ax, fullpath, 'Resolution', 300);
            case '.pdf'
                exportgraphics(ax, fullpath, 'ContentType', 'vector');
            otherwise
                disp('Unsupported file format');
        end
        
        disp(['Image saved as: ' fullpath]);
    end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {207, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '     Button Group';
            app.linearPatternButton = uiradiobutton(app.ButtonGroup);
            app.linearPatternButton.Text = 'linearPattern';
            app.linearPatternButton.Value = true;
            app.closedPatternButton = uiradiobutton(app.ButtonGroup);
            app.closedPatternButton.Text = 'closedPattern';
            app.inputcolorseriesLabel = uilabel(app.LeftPanel);
            app.inputcolorseriesLabel.Text = 'input color series';
            app.inputColorSeries = uieditfield(app.LeftPanel, 'text');
            app.inputColorSeries.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.inputColorSeries.Tag = 'inputColorSeries';
            app.inputColorSeries.Value = '1,1,1,1';
            app.exportgraphicsButton = uibutton(app.LeftPanel, 'push');
            app.exportgraphicsButton.ButtonPushedFcn = createCallbackFcn(app, @exportgraphicsButtonPushed, true);
            app.exportgraphicsButton.Text = 'export graphics';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patternDisplayApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end